classdef Daoham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DaohamTab                      matlab.ui.container.Tab
        KtquLabel                      matlab.ui.control.Label
        current                        matlab.ui.control.Label
        chongiatrisaiso                matlab.ui.control.ListBox
        GitrsaisListBoxLabel           matlab.ui.control.Label
        TnhButton                      matlab.ui.control.Button
        kq                             matlab.ui.control.Label
        giatricantinhdaoham            matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        pp                             matlab.ui.control.ListBox
        PhngphpohmListBoxLabel         matlab.ui.control.Label
        OhhoacOh2                      matlab.ui.control.NumericEditField
        NhpgitrOhhocLabel              matlab.ui.control.Label
        hamso                          matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        nhapx                          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        nhapy                          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhButton
        function TnhButtonPushed(app, event)
            xa = app.nhapx.Value;
            ya = app.nhapy.Value;
            h = app.OhhoacOh2.Value;
            xct = app.giatricantinhdaoham.Value;
            f=app.hamso.Value;
            sosanh='';
            syms x;
            if(strcmp(xa,sosanh)==1 && strcmp(ya,sosanh)==1 && strcmp(f,sosanh)==1)
                trangthaidacbiet=1;
            elseif(strcmp(xa,sosanh)==0 && strcmp(ya,sosanh)==0 && strcmp(f,sosanh)==1)
                y(x) = Lagrange(str2num(xa), str2num(ya), x);
                trangthaidacbiet=0;
            elseif(strcmp(xa,sosanh)==1 && strcmp(ya,sosanh)==1 && strcmp(f,sosanh)==0)
                y= str2func(['@(x)',f]);
                trangthaidacbiet=0;
            else
                tempname='Vui long nhap lai';
                app.kq.Text=tempname;
                trangthaidacbiet=1;
            end
            if(trangthaidacbiet==0)
            switch app.pp.Value
                case 'Tiến'
                    switch app.chongiatrisaiso.Value
                        case 'O(h)'
                            result = double((y(xct+h)-y(xct))/(h));
                            app.kq.Text=num2str(result);
                            temp='Tiến O(h)';
                            app.current.Text= temp;
                        otherwise
                            result = double((4*y(xct+h)-3*y(xct)-y(xct+2*h))/(2*h));
                            app.kq.Text=num2str(result);
                            temp='Tiến O(h^2)';
                            app.current.Text= temp;
                    end
                case 'Lùi'
                      switch app.chongiatrisaiso.Value
                        case 'O(h)'
                            result = double((y(xct)-y(xct-h))/(h));
                            app.kq.Text=num2str(result);
                            temp='Lùi O(h)';
                            app.current.Text= temp;
                        otherwise
                            result = double((y(xct-2*h)-4*y(xct-h)+3*y(xct))/(2*h));
                            app.kq.Text=num2str(result);
                            temp='Lùi O(h^2)';
                            app.current.Text= temp;
                      end
                otherwise
                    switch app.chongiatrisaiso.Value
                        case 'O(h^2)'
                            result = double((y(xct+h)-y(xct-h))/(2*h));
                            app.kq.Text=num2str(result);
                            temp='Trung tâm O(h^2)';
                            app.current.Text= temp;
                        otherwise
                            tempname='Vui long nhap lai';
                            app.kq.Text=tempname;
                            temp='';
                            app.current.Text= temp;
                    end
            end
            else
                tempname='Vui long nhap lai';
                app.kq.Text=tempname;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Daoham';
            app.NhpdliuyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.nhapy = uieditfield(app.DaohamTab, 'text');
            app.nhapy.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpdliuxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.nhapx = uieditfield(app.DaohamTab, 'text');
            app.nhapx.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.HocLabel = uilabel(app.DaohamTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamso = uieditfield(app.DaohamTab, 'text');
            app.hamso.Value = '2*x';
            app.NhpgitrOhhocLabel = uilabel(app.DaohamTab);
            app.NhpgitrOhhocLabel.Text = 'Nhập bước h';
            app.OhhoacOh2 = uieditfield(app.DaohamTab, 'numeric');
            app.OhhoacOh2.Value = 1e-05;
            app.PhngphpohmListBoxLabel = uilabel(app.DaohamTab);
            app.PhngphpohmListBoxLabel.Text = {'Phương pháp'; 'đạo hàm'};
            app.pp = uilistbox(app.DaohamTab);
            app.pp.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.pp.Value = 'Tiến';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = {'Nhập giá trị cần'; 'tính đạo hàm'};
            app.giatricantinhdaoham = uieditfield(app.DaohamTab, 'numeric');
            app.giatricantinhdaoham.Value = 0.16;
            app.kq = uilabel(app.DaohamTab);
            app.kq.Text = '';
            app.TnhButton = uibutton(app.DaohamTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @TnhButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.GitrsaisListBoxLabel = uilabel(app.DaohamTab);
            app.GitrsaisListBoxLabel.Text = 'Giá trị sai sô';
            app.chongiatrisaiso = uilistbox(app.DaohamTab);
            app.chongiatrisaiso.Items = {'O(h)', 'O(h^2)'};
            app.chongiatrisaiso.Value = 'O(h)';
            app.current = uilabel(app.DaohamTab);
            app.current.Text = 'Phương pháp hiện tại';
            app.KtquLabel = uilabel(app.DaohamTab);
            app.KtquLabel.Text = 'Kết quả:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Daoham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end